classdef vid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CloseApplicationButton      matlab.ui.control.Button
        UpdateParametersButton      matlab.ui.control.Button
        ExposureModeDropDownLabel   matlab.ui.control.Label
        ExposureModeDropDown        matlab.ui.control.DropDown
        GainEditFieldLabel          matlab.ui.control.Label
        GainEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        BrightnessEditFieldLabel    matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
        FrameRateModeDropDownLabel  matlab.ui.control.Label
        FrameRateModeDropDown       matlab.ui.control.DropDown
        ShutterModeDropDownLabel    matlab.ui.control.Label
        ShutterModeDropDown         matlab.ui.control.DropDown
        ExposureTimeEditFieldLabel  matlab.ui.control.Label
        ExposureTimeEditField       matlab.ui.control.NumericEditField
        VideoCaptureOnLampLabel     matlab.ui.control.Label
        VideoCaptureOnLamp          matlab.ui.control.Lamp
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        FileNameEditFieldLabel      matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        DurationsEditFieldLabel     matlab.ui.control.Label
        DurationsEditField          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Video = vid_config();
        vid;
        fid;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VideoCaptureOnLamp.Enable = 'off';
            
            %app.vid = videoinput('winvideo', app.videoInputNumber);
            app.vid = videoinput('pointgrey', 2, 'F7_Raw16_1024x768_Mode2');
            
            % m = load([INSERT FILEPATH TO EXISTING PROPERTIES HERE!]);
            app.ExposureModeDropDown.Value = app.Video.ExposureMode;
            app.FrameRateModeDropDown.Value = app.Video.FrameRateMode;
            app.GainEditField.Value = app.Video.Gain;
            app.GammaEditField.Value = app.Video.Gamma;
            app.BrightnessEditField.Value = app.Video.Brightness;
            app.ShutterModeDropDown.Value = app.Video.ShutterMode;
            app.ExposureTimeEditField.Value = app.Video.ExposureTime;
            app.DurationsEditField.Value = 60;
            
            src = getselectedsource(app.vid); 
            src.ExposureMode = app.Video.ExposureMode;
            src.FrameRateMode = app.Video.FrameRateMode;
            src.ShutterMode = app.Video.ShutterMode;
            src.Gain = app.Video.Gain;
            src.Gamma = app.Video.Gamma;
            src.Brightness = app.Video.Brightness;
            src.Shutter = app.Video.ExposureTime;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
    
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            hImage = imshow(uint16(zeros(imageRes)));
            axis image
            
            hImage.UserData{1} = false;
            hImage.UserData{2} = false;
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @vid_livehist_new);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end
            
            % Setting Frames Per Trigger
            Fps = 1 / (app.Video.ExposureTime/1000);
            app.vid.FramesPerTrigger = Fps * app.DurationsEditField.Value;
            triggerconfig(app.vid, 'manual');
            
        end

        % Button pushed function: CloseApplicationButton
        function CloseApplicationButtonPushed(app, event)
            close all
            closereq
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            stop(app.vid)
            
            close all
            
            src.ExposureMode = app.ExposureModeDropDown.Value;
            src.FrameRateMode = app.FrameRateModeDropDown.Value;
            src.ShutterMode = app.ShutterModeDropDown.Value;
            src.Gain = app.GammaEditField.Value;
            src.Gamma = app.GammaEditField.Value;
            src.Brightness = app.BrightnessEditField.Value;
            src.Shutter = app.ExposureTimeEditField.Value;
            
            vidRes = app.vid.VideoResolution;
            
            imageRes = fliplr(vidRes);
    
            f = figure('Visible', 'off', 'Units', 'normalized');
            ax = subplot(2, 1, 1);
            set(ax, 'Units', 'normalized');
            set(ax, 'Position', [0.05, 0.4515, .9, 0.53]); 
            
            hImage = imshow(uint16(zeros(imageRes)));
            axis image
        
            hImage.UserData{1} = false;
            hImage.UserData{2} = false;
            
            setappdata(hImage, 'UpdatePreviewWindowFcn', @vid_livehist_new);
        %     hImage.CData = log10(double(hImage.CData));
            h = preview(app.vid, hImage);
            set(h, 'CDataMapping', 'scaled');
        
            switch nargout
                case 1
                    varargout{1} = f;
            end
            
            % Setting Frames Per Trigger
            Fps = 1 / (app.Video.ExposureTime/1000);
            app.vid.FramesPerTrigger = Fps * app.DurationsEditField.Value;
            triggerconfig(app.vid, 'manual');
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Turning on the vid capture lamp
            app.VideoCaptureOnLamp.Enable = 'on';
            
            start(app.vid)
     
            % Opening the bin file
            binfilename = [app.FileNameEditField.Value,'.bin'];
            if ~isempty(dir(binfilename))
                error('That file already exists. Change the filename and try again.');
            end
            
            app.fid = fopen(binfilename, 'w');
            
            NFramesTaken = 0;
            NFramesAvailable = 0;
            cnt = 0;
            trigger(app.vid);


            % Taking the video
            while(NFramesTaken < app.vid.FramesPerTrigger)
       
                if app.vid.FramesAvailable==0
                    disp('Looping through.');
                    continue;
                end

                cnt = cnt + 1;
                
                NFramesAvailable(cnt,1) = app.vid.FramesAvailable;
                NFramesTaken = NFramesTaken + NFramesAvailable(cnt,1);
                disp(['Num Grabbed Frames: ' num2str(NFramesAvailable(cnt,1)) '/' num2str(NFramesTaken)]);
            
                [data, ~, meta] = getdata(app.vid, NFramesAvailable(cnt,1));    
                
                disp(app.vid.FramesAvailable);
                
%                 abstime{cnt,1} = vertcat(meta(:).AbsTime);
%                 framenumber{cnt,1} = meta(:).FrameNumber;
%             
%                 [rows, cols, rgb, frames] = size(data);
             
%                 if cnt == 1
%                     firstframe = data(:,:,1);
%                 end
                    
                fwrite(app.fid, data, 'uint16');
            
                if ~mod(cnt,5)
                    drawnow;
                end

            
            end
            
            % Stops video collection
            app.VideoCaptureOnLamp.Enable = 'off';
            fclose(app.fid);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.VideoCaptureOnLamp.Enable = 'off';
            fclose(app.fid);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CloseApplicationButton = uibutton(app.UIFigure, 'push');
            app.CloseApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @CloseApplicationButtonPushed, true);
            app.CloseApplicationButton.Text = 'Close Application';
            app.UpdateParametersButton = uibutton(app.UIFigure, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.ExposureModeDropDownLabel = uilabel(app.UIFigure);
            app.ExposureModeDropDownLabel.Text = 'Exposure Mode';
            app.ExposureModeDropDown = uidropdown(app.UIFigure);
            app.ExposureModeDropDown.Items = {'on', 'off'};
            app.ExposureModeDropDown.Value = 'on';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrightnessEditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessEditFieldLabel.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameRateModeDropDownLabel = uilabel(app.UIFigure);
            app.FrameRateModeDropDownLabel.Text = 'Frame Rate Mode';
            app.FrameRateModeDropDown = uidropdown(app.UIFigure);
            app.FrameRateModeDropDown.Items = {'on', 'off'};
            app.FrameRateModeDropDown.Value = 'on';
            app.ShutterModeDropDownLabel = uilabel(app.UIFigure);
            app.ShutterModeDropDownLabel.Text = 'Shutter Mode';
            app.ShutterModeDropDown = uidropdown(app.UIFigure);
            app.ShutterModeDropDown.Items = {'manual'};
            app.ShutterModeDropDown.Value = 'manual';
            app.ExposureTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.VideoCaptureOnLampLabel = uilabel(app.UIFigure);
            app.VideoCaptureOnLampLabel.Text = 'Video Capture On?';
            app.VideoCaptureOnLamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = vid_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end